classdef CovidDataVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure  matlab.ui.Figure
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        AveragedofDaysSliderLabel  matlab.ui.control.Label
        AveragedofDaysSlider       matlab.ui.control.Slider
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ObservationDates % Data collection dates
        GlobalData       % Stores global covid_data
        CountriesList    % List of countries
        CurrentCountry   % Handle to currently selected country object
        CurrentPlotData  % Handle of the currently selected country on state "All"
        AveragingWindow  % Store the value to be used for movmean
    end
    
    methods (Access = private)
        
        function setInitialValues(app)
            % Set initial value of Average slider
            app.AveragedofDaysSlider.Value = app.AveragedofDaysSlider.Limits(1);
            app.AveragingWindow = app.AveragedofDaysSlider.Value;
            % Set initial value of Country list box
            app.CountryListBox.Items = app.CountriesList;
            % Set initial value of State list box
            app.updateStateListBox();
            % Set initial data to plot to both
            app.BothButton.Value = true;
            app.DeathsButton.Value = false;
            app.CasesButton.Value = false;
            % Set initial option to cumulative
            app.CumulativeButton.Value = true;
            app.DailyButton.Value = false;
            % Set initial plot title
            %app.UIAxes.Title.String = "Cumulative Number of Cases and Deaths Globally";
        end
        
        function populateGlobalDataAndCountriesList(app, covid_data)
            % Populate global data
            app.GlobalData = Global();
            app.CountriesList = ("Global");
            total_cases = [];
            total_deaths = [];
            for i=2:length(covid_data)
                country_name = string(covid_data{i,1});
                if ~isempty(app.GlobalData.Countries) && app.GlobalData.Countries(end).Name == country_name
                    country = app.GlobalData.Countries(end);
                else
                    app.CountriesList = [app.CountriesList; country_name];
                    country = Country(country_name);
                    app.GlobalData.Countries = [app.GlobalData.Countries; country];
                end
                stateName = string(covid_data{i,2});
                cases_and_deaths = [covid_data{i,3:end}];
                cases = cases_and_deaths(1,1:2:end)'; % Get the cases
                deaths = cases_and_deaths(1,2:2:end)'; % Get the deaths
                if strcmp("", stateName)
                    stateName = "All";
                    total_cases = [total_cases; cases'];
                    total_deaths = [total_deaths; deaths'];
                end
                country.StateList = [country.StateList; stateName];
                state = State(stateName);
                state.Cases = cases;
                state.Deaths = deaths;
                country.States = [country.States; state];
            end
            app.GlobalData.Cases = sum(total_cases)';
            app.GlobalData.Deaths = sum(total_deaths)';
        end
        
        function updateStateListBox(app)
            if strcmp(app.CountryListBox.Value, "Global")
                app.StateorRegionListBox.Items = "All";
                app.CurrentPlotData = app.GlobalData;
            else
                % Retrieve States according to country
                for i=1:length(app.GlobalData.Countries)
                    if app.CountryListBox.Value == app.GlobalData.Countries(i).Name
                        app.CurrentCountry = app.GlobalData.Countries(i); % Store a handle of the current country to be used by State List box
                        app.StateorRegionListBox.Items = app.CurrentCountry.StateList;
                        app.CurrentPlotData = app.GlobalData.Countries(i).States(1); % Always start with "All"
                        break
                    end
                end
            end
        end
        
        function updateCurrentPlotData(app)
            for i=1:length(app.CurrentCountry.States)
                if strcmp(app.StateorRegionListBox.Value, app.CurrentCountry.States(i).Name)
                    app.CurrentPlotData = app.CurrentCountry.States(i);
                    break
                end
            end
        end
        
        function [cases, deaths] = calculateData(app)
            cases = [];
            deaths = [];
            if app.DailyButton.Value
                if app.CasesButton.Value
                    cases = app.CurrentPlotData.Cases - [0; app.CurrentPlotData.Cases(1:end-1)];
                elseif app.DeathsButton.Value
                    deaths = app.CurrentPlotData.Deaths - [0; app.CurrentPlotData.Deaths(1:end-1)];
                else
                    cases = app.CurrentPlotData.Cases - [0; app.CurrentPlotData.Cases(1:end-1)];
                    deaths = app.CurrentPlotData.Deaths - [0; app.CurrentPlotData.Deaths(1:end-1)];
                end
            else
                cases = app.CurrentPlotData.Cases;
                deaths = app.CurrentPlotData.Deaths;
            end
            if app.AveragingWindow > 1
                % Calculate average if averaging window is greater than 1
                if ~isempty(cases)
                    average_cases = movmean(cases,[fix(app.AveragingWindow)-1 0]);
                    cases = average_cases(fix(app.AveragingWindow): end);
                end
                if ~isempty(deaths)
                    average_deaths = movmean(deaths,[fix(app.AveragingWindow)-1 0]);
                    deaths = average_deaths(fix(app.AveragingWindow): end);
                end
            end
        end
        
        function plotData(app)
            [cases, deaths]= app.calculateData();
            timeline = app.ObservationDates(fix(app.AveragingWindow):end);
            cla(app.UIAxes, "reset"); % Remove previous axes
            hold(app.UIAxes, "on");
            if app.CasesButton.Value
                bar(app.UIAxes, timeline, cases);
                ylim(app.UIAxes, [0 inf]);
                ylim(app.UIAxes, "manual");
            elseif app.DeathsButton.Value
                plot(app.UIAxes, timeline, deaths,"r");
                ylim(app.UIAxes, [0 inf]);
                ylim(app.UIAxes, "manual");
            else
                yyaxis(app.UIAxes, "left");
                bar(app.UIAxes, timeline, cases);
                ylim(app.UIAxes, [0 inf]);
                ylim(app.UIAxes, "manual");
                yyaxis(app.UIAxes, "right");
                plot(app.UIAxes, timeline, deaths,"r");
                ylim(app.UIAxes, [0 inf]);
                ylim(app.UIAxes, "manual");
            end
            xlim(app.UIAxes, [timeline(1), timeline(end)]);
            xlim(app.UIAxes, "manual");
            datetick(app.UIAxes, 'x', 6);
            set(app.UIAxes, "box", "off"); % remove unnecessary plot ticks
            hold(app.UIAxes, "off");
        end
        
        function createPlotTitle(app)
            title_string_spec = "%s Number of %s %s %s"; % Daily or Cumulative, Cases or Deaths, Country or State, Average
            % String for Option
            if app.DailyButton.Value
                option_str = "Daily";
            else
                option_str = "Cumulative";
            end
            % String for Data
            if app.CasesButton.Value
                data_str = "Cases";
            elseif app.DeathsButton.Value
                data_str = "Deaths";
            else
                data_str = "Cases and Deaths";
            end
            if ~strcmp(app.CountryListBox.Value,"Global")
                % String for location
                if strcmp(app.StateorRegionListBox.Value,"All")
                    % Use country if "All" is selected as state
                    location_str = sprintf("in %s", app.CountryListBox.Value);
                else
                    % Use state
                    location_str = sprintf("in %s", app.StateorRegionListBox.Value);
                end
            else
                location_str = "Globally";
            end
            % Averaging string
            if app.AveragingWindow > 1
                average_str = sprintf("(%d-day mean)", fix(app.AveragingWindow));
            else
                average_str = "";
            end
            app.UIAxes.Title.String = sprintf(title_string_spec, option_str, data_str, location_str, average_str);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            % Store data collection dates
            table_width = width(covid_data);
            for i=3:table_width
                app.ObservationDates = [app.ObservationDates; datenum(covid_data{1,i})];
            end
            app.populateGlobalDataAndCountriesList(covid_data);
            app.setInitialValues();
            app.plotData();
            app.createPlotTitle();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.updateStateListBox();
            app.plotData();
            app.createPlotTitle();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.plotData();
            app.createPlotTitle();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.updateCurrentPlotData();
            app.plotData();
            app.createPlotTitle();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.plotData();
            app.createPlotTitle();
        end

        % Value changing function: AveragedofDaysSlider
        function AveragedofDaysSliderValueChanging(app, event)
            app.AveragingWindow = event.Value;
            app.plotData();
            app.createPlotTitle();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure.Name = 'COVID-19 Data Visualization (Data Source: John Hopkins University)';
            app.CountryListBoxLabel = uilabel(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofDaysSliderLabel = uilabel(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.AveragedofDaysSliderLabel.Text = 'Averaged # of Days';
            app.AveragedofDaysSlider = uislider(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.AveragedofDaysSlider.Limits = [1 15];
            app.AveragedofDaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofDaysSliderValueChanging, true);
            app.AveragedofDaysSlider.Value = 1;
            app.UIAxes = uiaxes(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XLim = [0 1];
            % Show the figure after all components are created
            app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidDataVisualizer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure)
        end
    end
end